classdef Interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ConnexionTab                    matlab.ui.container.Tab
        InitialisationdelaconnexionLabel  matlab.ui.control.Label
        openport                        matlab.ui.control.Button
        closeport                       matlab.ui.control.Button
        ChoixduportCOMEditFieldLabel    matlab.ui.control.Label
        port                            matlab.ui.control.EditField
        tatdelaconnexionEditFieldLabel  matlab.ui.control.Label
        portState                       matlab.ui.control.EditField
        CommandesmanuelsTab             matlab.ui.container.Tab
        CommandesmanuelLabel            matlab.ui.control.Label
        SendButton                      matlab.ui.control.Button
        InstructionDropDownLabel        matlab.ui.control.Label
        instruction                     matlab.ui.control.DropDown
        TypeDropDownLabel               matlab.ui.control.Label
        type                            matlab.ui.control.DropDown
        ValueEditFieldLabel             matlab.ui.control.Label
        value                           matlab.ui.control.NumericEditField
        MoteurDropDownLabel             matlab.ui.control.Label
        moteur                          matlab.ui.control.DropDown
        MotorBankDropDownLabel          matlab.ui.control.Label
        bank                            matlab.ui.control.DropDown
        Byte                            matlab.ui.control.EditField
        StatusLabel                     matlab.ui.control.Label
        status_reception                matlab.ui.control.EditField
        Reply                           matlab.ui.control.EditField
        RceptionLabel                   matlab.ui.control.Label
        HostEditFieldLabel              matlab.ui.control.Label
        Host_Rec                        matlab.ui.control.NumericEditField
        TargetEditFieldLabel            matlab.ui.control.Label
        Target_Rec                      matlab.ui.control.NumericEditField
        StatusEditFieldLabel            matlab.ui.control.Label
        Status_Rec                      matlab.ui.control.NumericEditField
        InstrEditFieldLabel             matlab.ui.control.Label
        Instr_Rec                       matlab.ui.control.NumericEditField
        DatagramRceptionLabel           matlab.ui.control.Label
        DatagramTransmissionLabel       matlab.ui.control.Label
    end


    properties (Access = public)
        table = load('data.mat'); % Load tableau des fonctions TMCL 
    end

    properties (Access = public)
        fid;
        Tx_value;
        Rx_value;
        state;
    end

     methods (Access = public)
%--------------------------------------------------------------------------------------------------------------%
%Fonction permettant de set les valeurs limites pouvant être entrée par l'utilisateur.
         function ValueState = FindLimits(app)
         
            %Find default Upper & Lower limit
            testind = cellfun(@(x)isequal(x,app.type.Value),values(app.table.type{app.instruction.Value}));    %Find key with value
            testkeys = keys(app.table.type{app.instruction.Value});
            msg_key = testkeys(testind) ;
            lower_limit = app.table.valeur_min{app.instruction.Value}(char(msg_key));
            upper_limit = app.table.valeur_max{app.instruction.Value}(char(msg_key));

            if lower_limit == upper_limit
                ValueState = 'off' ;    %Si aucune valeur ne peut être entrée, l'entrée de valeur est désactivée
                app.value.Value = 0;    % AJOUT: MET LA VALEURE 0 PAR DEFAUT LORSQUE DESACTIVÉ
            else
                ValueState = 'on' ;
                app.value.Limits = [lower_limit upper_limit];
            end             
         
         end
%--------------------------------------------------------------------------------------------------------------%  
% Fonction qui ecrit les bytes et qui lit la reponse         
         function [answer, byte] = WriteRead(app) % Fonction qui ecrit les bytes et qui lit la reponse
            
            n = int32(app.value.Value);
            
            byte(1:4) = uint8([str2double(app.moteur.Value),app.instruction.Value,app.type.Value,app.bank.Value]);
            byte(5)= uint8(bitand(bitshift(n,-24),255) );
            byte(6)= uint8(bitand(bitshift(n,-16),255) );
            byte(7)= uint8(bitand(bitshift(n,-8),255) );
            byte(8)= uint8(bitand(n,255) );
            byte(9)=uint8(bitand(sum(byte(1:8)),255) );
            
            answer = byte;   %Pour tests                            %A commenter si on decommente les 2 prochaines lignes
            
%             fwrite(app.fid, byte);                                %A decommenter pour faire l'ecriture au controleur
%             answer = fread(app.fid, 9, 'uint8');                  %A decommenter pour faire la lecture de la reponse du controleur
        end
%--------------------------------------------------------------------------------------------------------------%         
    %Fonction permettant de voir le status du reply
         function status = FindStatus(app)
             
             if app.Tx_value(3) == 100                 
                status = 'Successfully executed, no error';                
             elseif app.Tx_value(3) == 101                 
                 status = 'Command loaded into TMCL program EEPROM';                 
                 elseif app.Tx_value(3) == 1
                     status = 'Wrong checksum';
                     elseif app.Tx_value(3) == 2
                         status = 'Invalid command';
                         elseif app.Tx_value(3) == 3
                             status = 'Wrong type';
                             elseif app.Tx_value(3) == 4
                                 status ='Invalid value';
                                 elseif app.Tx_value(3) == 5
                                     status ='Configuration EEPROM locked';
                                     elseif app.Tx_value(3) == 6
                                         status ='Command not available';
             else 
                 status = 'Error';      
             end
         end
%--------------------------------------------------------------------------------------------------------------%          
     end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Default instruction Items
            app.instruction.Items = app.table.instruction.keys;                    %Setter les titres des instructions :keys
            app.instruction.ItemsData = app.table.instruction.values;              %Setter les itemsData des instructions: values
            
            %Default type Items
            app.type.Items = app.table.type{1}.keys;                               %Setter les titres des types de la premiere instruction par defaut
            app.type.ItemsData = app.table.type{1}.values;                         %Setter les itemsData des types de l'instruction par defaut
            
            %Find default Upper & Lower limit and update the access of app.value
            app.value.Enable = FindLimits(app);
            
            %Default bank items
            app.bank.Items = app.table.bank{1}.keys;                                %Setter les titres des bank de la premiere instruction
            app.bank.ItemsData = app.table.bank{1}.values;                          %Setter les itemsData des bank de l'instruction par defaut 
            
            %app.SendButton.Enable = 'off';           %À VOIR***                              %Envoie de message est par defaut non-accessible
            
            %Lire les capteurs....A faire ici


     %Setting des paramètres esthétiques
         %Onglet connexion
            app.ConnexionTab.BackgroundColor = [1 1 1];
         %Onglet commandes manuels   
            app.CommandesmanuelsTab.BackgroundColor = [1 1 1];            
          
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            
            %Envoyer et lire un message de 9 bytes
                   
              [app.Rx_value, app.Tx_value,] = WriteRead(app);                    %app.Rx_value est la lecture; app.Tx_value sont les bytes qu'on ecrit au controleur   
              app.state = FindStatus(app);
              
             %Écrit les bytes d'envoie dans l'interface
             app.Byte.Value = num2str(app.Tx_value);
             app.Reply.Value = num2str(app.Rx_value);
             app.status_reception.Value = app.state;           
             app.Host_Rec.Value = app.Rx_value(1) ;
             app.Target_Rec.Value = app.Rx_value(2) ;
             app.Status_Rec.Value = app.Rx_value(3) ;
             app.Instr_Rec.Value = app.Rx_value(4) ;
           
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Si l'interface se ferme; fermer la connexion serial si elle est presente
            try
                fclose(app.fid);
                delete(instrfindall);
                clear app.fid;
            end
            delete(app);                        
            
        end

        % Value changed function: bank
        function bankValueChanged(app, event)
            
            if (app.instruction.Value == 15)
                app.type.Items = app.table.gio{15}{app.bank.Value+1}.keys;
                app.type.ItemsData = app.table.gio{15}{app.bank.Value+1}.values; 
            else
                app.type.Items = app.table.type{app.instruction.Value}.keys;            %les bons keys (titres) du tableau avec app.instruction.Items
                app.type.ItemsData = app.table.type{app.instruction.Value}.values;      %les bons values du tableau avec app.instruction.ItemsData
            end
           
        end

        % Button pushed function: closeport
        function closeportButtonPushed(app, event)
            fclose(app.fid);                                                    %Fermer la connexion
            app.portState.FontColor = [0 0 0];                                  %Mettre en noire le texte
            app.portState.Value = 'Aucune connexion';                           %Texte qui affiche lorsqu'il n'y a aucune connexion
            app.openport.Enable = 'on';                                         %Mettre le bouton de connexion au port accessible
            app.SendButton.Enable = 'off';                                      %Mettre le bouton d'envoie de msg dans la tab commandes manuel inacessible
        end

        % Value changed function: instruction
        function instructionValueChanged(app, event)
            %Quand la valeure de l'instruction change; mise a jour des types selon l'instruction choisi
            
            app.type.Items = app.table.type{app.instruction.Value}.keys;            %les bons keys (titres) du tableau avec app.instruction.Items
            app.type.ItemsData = app.table.type{app.instruction.Value}.values;      %les bons values du tableau avec app.instruction.ItemsData
            
            %Quand la valeure de l'instruction change; mise a jour des bank selon l'instruction choisi
            
            app.bank.Items = app.table.bank{app.instruction.Value}.keys;            %les bons keys (titres) du tableau avec app.instruction.Items
            app.bank.ItemsData = app.table.bank{app.instruction.Value}.values;      %les bons values du tableau avec app.instruction.ItemsData
                            
            %Quand la valeure de l'instruction change; mise a jour des valeur limites admissibles et update de l'accès a app.value
            app.value.Enable = FindLimits(app);
            
        end

        % Button pushed function: openport
        function openportButtonPushed(app, event)
            
            %Essaie de se connecter au port et affiche un message pour montrer sa reussite
            try
                
                %app.fid = serial(strcat('COM',app.port.Value),'BaudRate',9600, 'DataBits', 8, 'Parity', 'none','StopBits', 1, 'FlowControl', 'none'); 
                app.fid = serial(strcat('COM',app.port.Value));
                set(app.fid, 'BaudRate',115200);
                set(app.fid, 'DataBits', 8);
                set(app.fid, 'Parity', 'none');
                set(app.fid, 'StopBits', 1);
                set(app.fid, 'FlowControl', 'none');
                set(app.fid, 'Terminator', 'LF');
                set(app.fid, 'Timeout',0.001);
                
                % Ouverture du port série
                fopen(app.fid);                                
                app.portState.FontColor = [0 1 0];                        %Mettre message en vert
                app.portState.Value = 'Bravo tu m"impressionnes';         %Mettre le message 
                app.openport.Enable = 'off';                              %Mettre le bouton de connexion indisponible car on a reussit la connexion
                app.SendButton.Enable = 'on';                             %Mettre le bouton d'envoie de message dans la tab manuel disponible
                
            %Si la connexion ne marche pas; afficher un texte en consequence
            catch
                app.portState.FontColor = [1 0 0];
                app.portState.Value = '...Tu vas finir par yarriver';
                app.openport.Enable = 'off';
                pause(3);
                app.portState.FontColor = [0 0 0];
                app.portState.Value = 'Aucune connexion';
                app.openport.Enable = 'on';
                app.SendButton.Enable = 'off';
            end
            
        end

        % Value changed function: type
        function typeValueChanged(app, event)
                
                %Quand la valeure du type change; mise a jour des valeurs limites selon type choisi et update de l'accès a app.value
                app.value.Enable = FindLimits(app);
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConnexionTab = uitab(app.TabGroup);
            app.ConnexionTab.Title = 'Connexion';
            app.InitialisationdelaconnexionLabel = uilabel(app.ConnexionTab);
            app.InitialisationdelaconnexionLabel.Text = 'Initialisation de la connexion';
            app.openport = uibutton(app.ConnexionTab, 'push');
            app.openport.ButtonPushedFcn = createCallbackFcn(app, @openportButtonPushed, true);
            app.openport.Text = 'Confirmer';
            app.closeport = uibutton(app.ConnexionTab, 'push');
            app.closeport.ButtonPushedFcn = createCallbackFcn(app, @closeportButtonPushed, true);
            app.closeport.Text = 'Fermer le port';
            app.ChoixduportCOMEditFieldLabel = uilabel(app.ConnexionTab);
            app.ChoixduportCOMEditFieldLabel.Text = 'Choix du port: COM';
            app.port = uieditfield(app.ConnexionTab, 'text');
            app.tatdelaconnexionEditFieldLabel = uilabel(app.ConnexionTab);
            app.tatdelaconnexionEditFieldLabel.Text = 'État de la connexion';
            app.portState = uieditfield(app.ConnexionTab, 'text');
            app.portState.Editable = 'off';
            app.portState.Value = 'Aucune connexion';
            app.CommandesmanuelsTab = uitab(app.TabGroup);
            app.CommandesmanuelsTab.Title = 'Commandes manuels';
            app.CommandesmanuelLabel = uilabel(app.CommandesmanuelsTab);
            app.CommandesmanuelLabel.Text = 'Commandes manuel';
            app.SendButton = uibutton(app.CommandesmanuelsTab, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.InstructionDropDownLabel = uilabel(app.CommandesmanuelsTab);
            app.InstructionDropDownLabel.Text = 'Instruction:';
            app.instruction = uidropdown(app.CommandesmanuelsTab);
            app.instruction.ValueChangedFcn = createCallbackFcn(app, @instructionValueChanged, true);
            app.TypeDropDownLabel = uilabel(app.CommandesmanuelsTab);
            app.TypeDropDownLabel.Text = 'Type:';
            app.type = uidropdown(app.CommandesmanuelsTab);
            app.type.ValueChangedFcn = createCallbackFcn(app, @typeValueChanged, true);
            app.ValueEditFieldLabel = uilabel(app.CommandesmanuelsTab);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.value = uieditfield(app.CommandesmanuelsTab, 'numeric');
            app.value.ValueDisplayFormat = '%.0f';
            app.MoteurDropDownLabel = uilabel(app.CommandesmanuelsTab);
            app.MoteurDropDownLabel.Text = 'Moteur #';
            app.moteur = uidropdown(app.CommandesmanuelsTab);
            app.moteur.Items = {'1', '2', '3', '4'};
            app.moteur.Value = '1';
            app.MotorBankDropDownLabel = uilabel(app.CommandesmanuelsTab);
            app.MotorBankDropDownLabel.Text = 'Motor/Bank';
            app.bank = uidropdown(app.CommandesmanuelsTab);
            app.bank.ValueChangedFcn = createCallbackFcn(app, @bankValueChanged, true);
            app.Byte = uieditfield(app.CommandesmanuelsTab, 'text');
            app.Byte.Editable = 'off';
            app.StatusLabel = uilabel(app.CommandesmanuelsTab);
            app.StatusLabel.Text = 'Status';
            app.status_reception = uieditfield(app.CommandesmanuelsTab, 'text');
            app.status_reception.Editable = 'off';
            app.Reply = uieditfield(app.CommandesmanuelsTab, 'text');
            app.Reply.Editable = 'off';
            app.RceptionLabel = uilabel(app.CommandesmanuelsTab);
            app.RceptionLabel.Text = 'Réception';
            app.HostEditFieldLabel = uilabel(app.CommandesmanuelsTab);
            app.HostEditFieldLabel.Text = 'Host :';
            app.Host_Rec = uieditfield(app.CommandesmanuelsTab, 'numeric');
            app.TargetEditFieldLabel = uilabel(app.CommandesmanuelsTab);
            app.TargetEditFieldLabel.Text = 'Target :';
            app.Target_Rec = uieditfield(app.CommandesmanuelsTab, 'numeric');
            app.StatusEditFieldLabel = uilabel(app.CommandesmanuelsTab);
            app.StatusEditFieldLabel.Text = 'Status :';
            app.Status_Rec = uieditfield(app.CommandesmanuelsTab, 'numeric');
            app.InstrEditFieldLabel = uilabel(app.CommandesmanuelsTab);
            app.InstrEditFieldLabel.Text = 'Instr. :';
            app.Instr_Rec = uieditfield(app.CommandesmanuelsTab, 'numeric');
            app.DatagramRceptionLabel = uilabel(app.CommandesmanuelsTab);
            app.DatagramRceptionLabel.Text = 'Datagram Réception';
            app.DatagramTransmissionLabel = uilabel(app.CommandesmanuelsTab);
            app.DatagramTransmissionLabel.Text = 'Datagram Transmission';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interface

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end