classdef Commandes_manuel < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        CommandesmanuelLabel      matlab.ui.control.Label
        InstructionDropDownLabel  matlab.ui.control.Label
        instruction               matlab.ui.control.DropDown
        TypeDropDownLabel         matlab.ui.control.Label
        type                      matlab.ui.control.DropDown
        ValueEditFieldLabel       matlab.ui.control.Label
        value                     matlab.ui.control.NumericEditField
        SendButton                matlab.ui.control.Button
        MoteurDropDownLabel       matlab.ui.control.Label
        moteur                    matlab.ui.control.DropDown
        BackButton                matlab.ui.control.Button
    end


    properties (Access = private)
        
    end

    properties (Access = public)
        table = load('table.mat'); % Load le tableau qui contient toutes les fonctions necessaires de TMCL
    end

    methods (Access = public)
        
        function answer = ReadWrite(app) % WORK IN PROGRESS
            
            n = int32(app.value.Value);
            
            byte(1:4) = uint8([str2double(app.moteur.Value),app.instruction.Value,app.type.Value,0]);  %Le 0 pas certain!!!
            byte(5)= uint8(bitand(bitshift(n,-24),255) );
            byte(6)= uint8(bitand(bitshift(n,-16),255) );
            byte(7)= uint8(bitand(bitshift(n,-8),255) );
            byte(8)= uint8(bitand(n,255) );
            byte(9)=uint8(bitand(sum(byte(1:8)),255) );
            
            byte
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
                                
            app.instruction.Items = app.table.instruction.keys;                    %Setter les titres des instructions :keys
            app.instruction.ItemsData = app.table.instruction.values;              %Setter les itemsData des instructions: values
            
            app.type.Items = app.table.type{1}.keys;                               %Setter les titres des types de la premiere instruction par defaut
            app.type.ItemsData = app.table.type{1}.values;                         %Setter les itemsData des types de l'instruction par defaut
            app.value.Limits = app.table.limitetype{1};                            %Setter les limites de valeurs du premier type par defaut.. A revoir
            
            
            %Lire les capteurs....A faire ici
        end

        % Button pushed function: SendButton
        function SendButtonPushed(app, event)
            
            %Envoyer un message de 9 bytes au controleur
            %Lire un message de 9 bytes du controleurs
            
            %reponse = WriteRead(app.moteur.Value, app.instruction.Value, app.type.Value, app.value.Value);
            ReadWrite(app)
            fprintf('--------------------------------')
            
        end

        % Value changed function: instruction
        function instructionValueChanged(app, event)
            %Mettre les bons types selon l'instruction
            app.type.Items = app.table.type{app.instruction.Value}.keys;            %les bons keys du tableau avec app.instruction.ItemsData
            app.type.ItemsData = app.table.type{app.instruction.Value}.values;     %les bons values du tableau avec app.instruction.ItemsData
        end

        % Value changed function: type
        function typeValueChanged(app, event)
            %Changer les limites selon le type
            %app.value.Limits = app.table.limitetype{app.instruction.Value}{app.type.Value};               %[x y] x etant min et y etant max A BIEN FAIRE ATTENTION
            
        end

        % Callback function
        function valueValueChanged(app, event)

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            
            %%Fermer le port s'il est present... fclose(fid)
            delete(app);
            
        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.CommandesmanuelLabel = uilabel(app.UIFigure);
            app.CommandesmanuelLabel.Text = 'Commandes manuel';
            app.InstructionDropDownLabel = uilabel(app.UIFigure);
            app.InstructionDropDownLabel.Text = 'Instruction:';
            app.instruction = uidropdown(app.UIFigure);
            app.instruction.ValueChangedFcn = createCallbackFcn(app, @instructionValueChanged, true);
            app.TypeDropDownLabel = uilabel(app.UIFigure);
            app.TypeDropDownLabel.Text = 'Type:';
            app.type = uidropdown(app.UIFigure);
            app.type.ValueChangedFcn = createCallbackFcn(app, @typeValueChanged, true);
            app.ValueEditFieldLabel = uilabel(app.UIFigure);
            app.ValueEditFieldLabel.Text = 'Value:';
            app.value = uieditfield(app.UIFigure, 'numeric');
            app.value.ValueDisplayFormat = '%.0f';
            app.SendButton = uibutton(app.UIFigure, 'push');
            app.SendButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonPushed, true);
            app.SendButton.Text = 'Send';
            app.MoteurDropDownLabel = uilabel(app.UIFigure);
            app.MoteurDropDownLabel.Text = 'Moteur #';
            app.moteur = uidropdown(app.UIFigure);
            app.moteur.Items = {'1', '2', '3', '4'};
            app.moteur.ItemsData = {'1', '2', '3', '4'};
            app.moteur.Value = '1';
            app.BackButton = uibutton(app.UIFigure, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Text = 'Back';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Commandes_manuel

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end